classdef PISdisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StateLabel                matlab.ui.control.Label
        powerlight                matlab.ui.control.Lamp
        PowerSwitchLabel          matlab.ui.control.Label
        PowerSwitch               matlab.ui.control.Switch
        BaselineRateLabel         matlab.ui.control.Label
        baselinerate              matlab.ui.control.Knob
        InjectionSwitchLabel      matlab.ui.control.Label
        InjectionSwitch           matlab.ui.control.ToggleSwitch
        UnlockButton              matlab.ui.control.Button
        LockButton                matlab.ui.control.Button
        PerBolusKnobLabel         matlab.ui.control.Label
        PerBolusKnob              matlab.ui.control.Knob
        DayamountGauge            matlab.ui.control.Gauge
        HourAmountGauge           matlab.ui.control.Gauge
        DayAmountEditFieldLabel   matlab.ui.control.Label
        DayAmount                 matlab.ui.control.NumericEditField
        HourAmountEditFieldLabel  matlab.ui.control.Label
        HourAmount                matlab.ui.control.NumericEditField
        TimeScalingSliderLabel    matlab.ui.control.Label
        TimeScalingSlider         matlab.ui.control.Slider
        TimesEditFieldLabel       matlab.ui.control.Label
        TimesEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        sf % stateflow function
        cc % instance for class
        Patient_UI % multiwindow 
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        
        function live(app,~,~)
            
            if length(app.cc.inject_log) > 3600 && (app.HourAmount.Value >= 0) && (strcmp(app.PowerSwitch.Value,'On'))
                app.HourAmountGauge.Value = app.HourAmountGauge.Value - app.cc.inject_log(length(app.cc.inject_log)-3600);
                app.HourAmount.Value = app.HourAmountGauge.Value;
            end
            if length(app.cc.inject_log) > 86400 && (app.DayAmount.Value >= 0)&& (strcmp(app.PowerSwitch.Value,'On'))
                app.DayamountGauge.Value = app.DayamountGauge.Value - app.cc.inject_log(length(app.cc.inject_log)- 86400);
                app.DayAmount.Value = app.DayamountGauge.Value;
            end
            if strcmp(app.InjectionSwitch.Value,'On') && (app.DayAmount.Value < 3 ) && (app.HourAmount.Value < 1)&& (strcmp(app.PowerSwitch.Value,'On'))
                  app.cc.inject(app.cc.inject_rate/60)
                  app.DayamountGauge.Value = app.DayamountGauge.Value + app.cc.inject_log(end);
                  app.DayAmount.Value = app.DayamountGauge.Value;
                  app.HourAmountGauge.Value = app.HourAmountGauge.Value + app.cc.inject_log(end);
                  app.HourAmount.Value = app.HourAmountGauge.Value;
            else
                app.cc.timefly
            end
            if ((app.DayAmount.Value >= 3 ) || (app.HourAmount.Value >= 1))&& (strcmp(app.PowerSwitch.Value,'On'))
                app.powerlight.Color = [1,1,0];
            else
                app.powerlight.Color = [0,1,0];
            end
            app.TimesEditField.Value = app.TimesEditField.Value + 1;
            
        end
        
        function wipe(app)
            try stop(timerfindall);    catch; end
            try delete(timerfindall);  catch; end
            app.HourAmount.Value = 0;
            app.DayAmount.Value = 0;
            app.DayamountGauge.Value = 0;
            app.HourAmountGauge.Value = 0;
            app.TimesEditField.Value = 0;
            app.baselinerate.Value = 0.01;
            app.PerBolusKnob.Value = 0.2;
            app.InjectionSwitch.Value = 'Off';
            app.cc.wipe;
            try app.Patient_UI.close; catch; end
            app.TimeScalingSlider.Value = 1;
        end
    end
    
    methods (Access = public)
        
        function  patient(app)
            if app.HourAmount.Value + app.PerBolusKnob.Value <= 1 && app.DayAmount.Value + app.PerBolusKnob.Value <= 3
                app.cc.patient_enmergency(app.cc.bolus_volume)
                app.DayamountGauge.Value = app.DayamountGauge.Value + app.cc.bolus_volume;
                app.DayAmount.Value = app.DayamountGauge.Value;
                app.HourAmountGauge.Value = app.HourAmountGauge.Value + app.cc.bolus_volume;
                app.HourAmount.Value = app.HourAmountGauge.Value;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sf = powerlightcontrol('app',app);
            app.cc = injector;
        end

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)
            value = app.PowerSwitch.Value;
            switch value
                case 'On'
                    ON(app.sf)
                    %app.live;
                    app.Patient_UI = patient_UI(app);
                    t = timer('TimerFcn',@(~,~)live(app));
                    t.Period = 1;
                    t.ExecutionMode = 'fixedRate';
                    start(t);
                case 'Off'
                    OFF(app.sf)
                    app.wipe;
                    
            end
        end

        % Value changed function: baselinerate
        function baselinerateValueChanged(app, event)
            value = app.baselinerate.Value;
            app.cc.update_rate(value);
            
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            LOCK(app.sf)
        end

        % Button pushed function: UnlockButton
        function UnlockButtonPushed(app, event)
            UNLOCK(app.sf)
        end

        % Value changed function: PerBolusKnob
        function PerBolusKnobValueChanged(app, event)
            value = app.PerBolusKnob.Value;
            app.cc.update_bolus(value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.PowerSwitch.Value,'Off')
                try app.wipe; catch;end
                delete(app);
            else
                errordlg('You must shutdown Power of PIS before close it','Force Quit Error','modal'); 
            end
           
        end

        % Value changed function: TimeScalingSlider
        function TimeScalingSliderValueChanged(app, event)
            value = app.TimeScalingSlider.Value;
            try stop(timerfindall);    catch; end
            try delete(timerfindall);  catch; end
            t = timer('TimerFcn',@(~,~)live(app));
            t.Period = 1/value;
            t.ExecutionMode = 'fixedRate';
            start(t);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            app.powerlight = uilamp(app.UIFigure);
            app.PowerSwitchLabel = uilabel(app.UIFigure);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.UIFigure, 'slider');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.BaselineRateLabel = uilabel(app.UIFigure);
            app.BaselineRateLabel.Text = 'Baseline Rate';
            app.baselinerate = uiknob(app.UIFigure, 'continuous');
            app.baselinerate.Limits = [0.01 0.1];
            app.baselinerate.MajorTicks = [0.01 0.03 0.05 0.065 0.08 0.1];
            app.baselinerate.ValueChangedFcn = createCallbackFcn(app, @baselinerateValueChanged, true);
            app.baselinerate.Value = 0.01;
            app.InjectionSwitchLabel = uilabel(app.UIFigure);
            app.InjectionSwitchLabel.Text = 'Injection';
            app.InjectionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.UnlockButton = uibutton(app.UIFigure, 'push');
            app.UnlockButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockButtonPushed, true);
            app.UnlockButton.Text = 'Unlock';
            app.LockButton = uibutton(app.UIFigure, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.PerBolusKnobLabel = uilabel(app.UIFigure);
            app.PerBolusKnobLabel.Text = 'Per Bolus';
            app.PerBolusKnob = uiknob(app.UIFigure, 'continuous');
            app.PerBolusKnob.Limits = [0.2 0.5];
            app.PerBolusKnob.ValueChangedFcn = createCallbackFcn(app, @PerBolusKnobValueChanged, true);
            app.PerBolusKnob.Value = 0.2;
            app.DayamountGauge = uigauge(app.UIFigure, 'circular');
            app.DayamountGauge.Limits = [0 3];
            app.HourAmountGauge = uigauge(app.UIFigure, 'circular');
            app.HourAmountGauge.Limits = [0 1];
            app.DayAmountEditFieldLabel = uilabel(app.UIFigure);
            app.DayAmountEditFieldLabel.Text = 'DayAmount';
            app.DayAmount = uieditfield(app.UIFigure, 'numeric');
            app.DayAmount.ValueDisplayFormat = '%.3f';
            app.DayAmount.Editable = 'off';
            app.HourAmountEditFieldLabel = uilabel(app.UIFigure);
            app.HourAmountEditFieldLabel.Text = 'HourAmount';
            app.HourAmount = uieditfield(app.UIFigure, 'numeric');
            app.HourAmount.ValueDisplayFormat = '%.3f';
            app.HourAmount.Editable = 'off';
            app.TimeScalingSliderLabel = uilabel(app.UIFigure);
            app.TimeScalingSliderLabel.Text = 'TimeScaling';
            app.TimeScalingSlider = uislider(app.UIFigure);
            app.TimeScalingSlider.Limits = [1 100];
            app.TimeScalingSlider.MajorTicks = [1 20 40 60 80 100];
            app.TimeScalingSlider.ValueChangedFcn = createCallbackFcn(app, @TimeScalingSliderValueChanged, true);
            app.TimeScalingSlider.MinorTicks = [];
            app.TimeScalingSlider.Value = 1;
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time(s)';
            app.TimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimesEditField.ValueDisplayFormat = '%.0f';
            app.TimesEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PISdisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end