classdef patient_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        PatientButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        PISdisplay % cooperate with another UI
    end
    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = public)
        
        function close(app)
            delete(app)
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: PatientButton
        function PatientButtonPushed(app, event)
            app.CallingApp.patient
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            errordlg('You cannot close Patient Panel Individually','Force Quit Error','modal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PatientButton = uibutton(app.UIFigure, 'push');
            app.PatientButton.ButtonPushedFcn = createCallbackFcn(app, @PatientButtonPushed, true);
            app.PatientButton.Text = 'Patient Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patient_UI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end